classdef MotorControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PosAxisTab                      matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        CustomeMode_ETab                matlab.ui.container.Tab
        CustomEncoderPulsesCheckBox     matlab.ui.control.CheckBox
        UpdateEncoderModeButton         matlab.ui.control.Button
        DecelDistanceEditField          matlab.ui.control.NumericEditField
        DecelDistanceEditFieldLabel     matlab.ui.control.Label
        MaintainDistanceEditField       matlab.ui.control.NumericEditField
        MaintainDistanceEditFieldLabel  matlab.ui.control.Label
        AccelDistanceEditField          matlab.ui.control.NumericEditField
        AccelDistanceEditFieldLabel     matlab.ui.control.Label
        SliderPIDtuningTab              matlab.ui.container.Tab
        AngleEditField                  matlab.ui.control.NumericEditField
        AngleEditFieldLabel             matlab.ui.control.Label
        SlidePIDCheckBox                matlab.ui.control.CheckBox
        KdSlider                        matlab.ui.control.Slider
        KdSliderLabel                   matlab.ui.control.Label
        KiSlider                        matlab.ui.control.Slider
        KiSliderLabel                   matlab.ui.control.Label
        KpSlider                        matlab.ui.control.Slider
        KpSliderLabel                   matlab.ui.control.Label
        PIDBLCTab                       matlab.ui.container.Tab
        VelDSlider                      matlab.ui.control.Slider
        VelDSliderLabel                 matlab.ui.control.Label
        VelISlider                      matlab.ui.control.Slider
        VelISliderLabel                 matlab.ui.control.Label
        VelPSlider                      matlab.ui.control.Slider
        VelPSliderLabel                 matlab.ui.control.Label
        SlidePIDBLCCheckBox             matlab.ui.control.CheckBox
        DliuthvthngsktniPanel           matlab.ui.container.Panel
        ExcelTableExportButton          matlab.ui.control.Button
        CONNECTButton                   matlab.ui.control.Button
        COMPortEditField                matlab.ui.control.EditField
        COMPortEditFieldLabel           matlab.ui.control.Label
        TruyenToiSTM32EditField         matlab.ui.control.EditField
        TruyenToiSTM32EditFieldLabel    matlab.ui.control.Label
        BaudrateEditField               matlab.ui.control.EditField
        BaudrateEditFieldLabel          matlab.ui.control.Label
        NhantuStm32EditField            matlab.ui.control.EditField
        NhantuStm32EditFieldLabel       matlab.ui.control.Label
        EncoderPanel                    matlab.ui.container.Panel
        SaisEditField_2                 matlab.ui.control.NumericEditField
        SaisEditField_2Label            matlab.ui.control.Label
        EncoderModeButtonGroup          matlab.ui.container.ButtonGroup
        ResetButton_2                   matlab.ui.control.RadioButton
        SetZeroButton                   matlab.ui.control.Button
        HomeButton                      matlab.ui.control.Button
        Mode4Button                     matlab.ui.control.RadioButton
        Mode3Button                     matlab.ui.control.RadioButton
        Mode2Button                     matlab.ui.control.RadioButton
        Mode1Button                     matlab.ui.control.RadioButton
        ThngscngtcLabel                 matlab.ui.control.Label
        ThngsvtrLabel                   matlab.ui.control.Label
        VtrmongmunEditField             matlab.ui.control.NumericEditField
        VtrmongmunEditFieldLabel        matlab.ui.control.Label
        VtrhintiEditField               matlab.ui.control.NumericEditField
        VtrhintiEditFieldLabel          matlab.ui.control.Label
        XunghintiEditField              matlab.ui.control.NumericEditField
        XunghintiEditFieldLabel         matlab.ui.control.Label
        BnknhvtmeEditField              matlab.ui.control.NumericEditField
        BnknhvtmeEditFieldLabel         matlab.ui.control.Label
        PIDControllerPanel              matlab.ui.container.Panel
        PosPulsesEditField              matlab.ui.control.NumericEditField
        PosPulsesEditFieldLabel         matlab.ui.control.Label
        PIDmodeButtonGroup              matlab.ui.container.ButtonGroup
        ResetButton                     matlab.ui.control.RadioButton
        EncoderModeButton               matlab.ui.control.RadioButton
        VelPotButton                    matlab.ui.control.RadioButton
        ManualButton                    matlab.ui.control.RadioButton
        PositionButton                  matlab.ui.control.RadioButton
        VelocityButton                  matlab.ui.control.RadioButton
        PulsesEditField                 matlab.ui.control.NumericEditField
        PulsesEditFieldLabel            matlab.ui.control.Label
        SetpointPulsesButtonGroup       matlab.ui.container.ButtonGroup
        YesButton                       matlab.ui.control.RadioButton
        NoPulsesButton                  matlab.ui.control.RadioButton
        PIDEditField                    matlab.ui.control.NumericEditField
        PIDEditFieldLabel               matlab.ui.control.Label
        UpdatePIDButton                 matlab.ui.control.Button
        SetpointSpeedPIDEditField       matlab.ui.control.NumericEditField
        SetpointSpeedPIDEditFieldLabel  matlab.ui.control.Label
        HsPEditField                    matlab.ui.control.NumericEditField
        HsPEditFieldLabel               matlab.ui.control.Label
        HsDEditField                    matlab.ui.control.NumericEditField
        HsDEditFieldLabel               matlab.ui.control.Label
        HsIEditField                    matlab.ui.control.NumericEditField
        HsIEditFieldLabel               matlab.ui.control.Label
        MotorControllerButtonGroup      matlab.ui.container.ButtonGroup
        OnlyuseinManualLabel            matlab.ui.control.Label
        ThuanChieuMotorButton           matlab.ui.control.Button
        ResetDriverButton               matlab.ui.control.Button
        CFRightEditField                matlab.ui.control.NumericEditField
        CFRightEditFieldLabel           matlab.ui.control.Label
        CFLeftEditField                 matlab.ui.control.NumericEditField
        CFLeftEditFieldLabel            matlab.ui.control.Label
        DaoChieuMotorButton             matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        PWMEditField                    matlab.ui.control.NumericEditField
        PWMEditFieldLabel               matlab.ui.control.Label
        Gauge                           matlab.ui.control.SemicircularGauge
        GaugeLabel                      matlab.ui.control.Label
        SpeedSlider                     matlab.ui.control.Slider
        SpeedSliderLabel                matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        s % Description
        t_line      % Bút vẽ đường biểu đồ
        t_line2
        startTime   % Thời điểm bắt đầu để tính trục X (thời gian)
        Datatable
       
    end
    
   methods (Access = private)
        function readSerialData(app, ~, ~)
            data = readline(app.s);
            data = strtrim(data);
            app.NhantuStm32EditField.Value = char(data);
            if isempty(data), return; end
            
            % Tách chuỗi theo khoảng trắng
            parts = strsplit(data); 
            t = seconds(datetime('now') - app.startTime);
            % Định dạng: Giờ:Phút:Giây.Milis
            currentTime = string(datetime('now', 'Format', 'HH:mm:ss.SSS'));
            
            % Khởi tạo dòng dữ liệu với Timestamp ở cột 1
            row = {currentTime, "Mode", char(data), 0, 0, 0};
            % t_ms = seconds(datetime('now') - app.startTime) * 1000;
            % row = {t_ms, "N/A", char(data), 0, 0, 0};
            % Duyệt qua từng phần tử đã tách (V... hoặc E...)
            for i = 1:length(parts)
                item = char(parts{i});
                if isempty(item), continue; end
                
                header = item(1);           % Lấy ký tự đầu (V hoặc E)
                val = str2double(item(2:end)); % Lấy giá trị số phía sau
                
                if ~isnan(val)
                    switch header
                        case 'V' % --- XỬ LÝ RIÊNG CHO VẬN TỐC ---
                            app.PWMEditField.Value = app.SetpointSpeedPIDEditField.Value * 8.78;
                            app.Gauge.Value = val;
                            addpoints(app.t_line, t, val);
                            app.UIAxes.XLim = [t-10, t];
                            row{6} = val; %output thực tế, cột 6
                            app.Gauge.Value = val; % Vẫn cho hiển thị để nhìn cho vui
                            
                        case 'E' % --- XỬ LÝ RIÊNG CHO VỊ TRÍ (ENCODER) ---
                            app.VtrhintiEditField.Value = val;
                            app.XunghintiEditField.Value = val * 422.4 / 8.0; % Hiện ở khung Encoder
                            v_real = app.VtrhintiEditField.Value; 
                            % Lấy số từ ô Vị trí mong muốn
                            v_set = app.VtrmongmunEditField.Value; 
                            current_mm = val;
                            % Tính và hiển thị
                            app.SaisEditField_2.Value = v_set - v_real;
                            addpoints(app.t_line2, t, current_mm);    % Vẽ đồ thị vị trí
                            app.UIAxes_2.XLim = [t-10, t];     % Cuộn trục X đồ thị 2
                        case 'D'
                            app.AngleEditField = val;
                        case 'M'
                            row{2} = string(val); %pid mode cot 2
                        case 'S'
                            row{3} = string(val); %motor status cột 3
                        case 'T'
                            row{4} = val; %target_mm cột 4
                        case 'Y'
                            row{5} = val; %target_vel cột 5
               
                    end
                end
            end     
         app.Datatable = [app.Datatable; cell2table(row, 'VariableNames', app.Datatable.Properties.VariableNames)];
         drawnow limitrate; 
        end
        % Code trong phần Functions (Private)
        function SendAllPID(app, p, i, d)
            % Kiểm tra nếu Checkbox "Slide PID" được tích mới cho gửi
            if app.SlidePIDCheckBox.Value == 1
                % Đóng gói 3 thông số theo định dạng: S Kp Ki Kd\n
                cmd = sprintf("u %.2f i %.2f o %.2f\n", p, i, d);
                
                % Kiểm tra cổng Serial có đang mở không
                if ~isempty(app.s) && isvalid(app.s)
                    write(app.s, cmd, "string");
                    flush(app.s); % Đẩy dữ liệu đi ngay lập tức
                    
                    % Hiển thị lên ô Raw Data để Nhóm trưởng dễ theo dõi
                    app.TruyenToiSTM32EditField.Value = cmd;
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STOPButton
        function StopButtonPushed(app, event)
            if ~isempty(app.s) && isvalid(app.s)
                write(app.s, "s", "string");
                app.TruyenToiSTM32EditField.Value = "s";
            end
        end

        % Button pushed function: CONNECTButton
        function Connect(app, event)
            % 1. Đọc tên cổng COM từ ô nhập liệu (ví dụ: COM3)
            port = app.COMPortEditField.Value; 
            
            % 2. Đọc Baudrate từ ô nhập (thường HC-06 mặc định là 9600)
            baud = str2double(app.BaudrateEditField.Value);
            
            % 3. Mở kết nối
            try
                app.s = serialport(port, baud);
                % Thông báo nhanh trên cửa sổ lệnh để biết là chạy rồi
                disp("Đã kết nối với HC-06 tại " + port); 
                app.t_line = animatedline(app.UIAxes, 'Color', 'r', 'LineWidth', 1.5);
                app.t_line2 = animatedline(app.UIAxes_2, 'Color', 'r', 'LineWidth', 1.5);
                app.startTime = datetime('now'); % Bắt đầu tính thời gian từ đây
                
                % QUAN TRỌNG: Cứ thấy dấu xuống dòng là tự gọi hàm readSerialData
                configureTerminator(app.s, "LF");
                configureCallback(app.s, "terminator", @app.readSerialData);
            catch
                disp("Lỗi! Không tìm thấy cổng COM này");
             % Tạo đường vẽ màu đỏ cho biểu đồ tốc độ
            end
          
% Khởi tạo tại nút Connect:
            % app.Datatable = table('Size', [0 6], 'VariableTypes', ...
            %     ["double", "string", "string", "double", "double", "double"], ...
            %     'VariableNames', ["Time_ms", "Mode", "Status", "Target_mm", "Target_V", "Current_V"]);
            % Khởi tạo tại nút Connect:
            varNames = ["Timestamp", "Mode", "Status", "Target_mm", "Target_V", "Current_V"];
            varTypes = ["string", "string", "string", "double", "double", "double"];
            
            app.Datatable = table('Size', [0 6], 'VariableTypes', varTypes, 'VariableNames', varNames);
        end

        % Value changing function: SpeedSlider
        function SpeedChange(app, event)
           
            % 1. Lấy giá trị nguyên (0-100)
            val = uint8(round(event.Value)); 
            
            if ~isempty(app.s) && isvalid(app.s)
                % 2. Gửi mã ASCII của chữ 'V' (86)
                write(app.s, 86, "uint8"); 
                
                % 3. Gửi con số thực tế ngay sau đó
                write(app.s, val, "uint8");
                
                % Cập nhật Gauge cho mượt
                app.Gauge.Value = val;
                
                % In ra Command Window để check
                fprintf("Đã gửi Speed: V %d\n", val);
                app.TruyenToiSTM32EditField.Value = "V";
            end
        end

        % Button pushed function: ResetDriverButton
        function ResetDriver(app, event)
            if ~isempty(app.s) && isvalid(app.s)
                % Gửi lệnh M để tắt chế độ PID trên STM32
                writeline(app.s, "L");
                % Hiển thị trạng thái lên UI để Ân biết
                app.TruyenToiSTM32EditField.Value = "L (Latch Mode)";
            end
        end

        % Selection changed function: PIDmodeButtonGroup
        function PIDMode(app, event)
            selectedButton = app.PIDmodeButtonGroup.SelectedObject; 
            if selectedButton == app.VelocityButton %% Mode Velocity
                writeline(app.s, 'b');
            elseif selectedButton == app.PositionButton %% Mode Postion
                writeline(app.s, 'a');
            elseif selectedButton == app.ManualButton %% Mode Manual
                writeline(app.s, 'c');
            elseif selectedButton == app.VelPotButton %% Mode Velocity controlled by Pot (PID + manual)
                writeline(app.s, 'p');
            elseif selectedButton == app.EncoderModeButton %% 4 Mode Encoder
                writeline(app.s, 'e');
            elseif selectedButton == app.ResetButton  %% reset PID
                writeline(app.s, 'J');
            end        
        end

        % Button pushed function: SetZeroButton
        function SetZero(app, event)
                        if ~isempty(app.s) && isvalid(app.s)
                            write(app.s, "z", "string"); % Gửi chữ R (Reverse)
                            disp("Đã gửi lệnh: (z)");
                            app.VtrmongmunEditField.Value = 0;
                            app.TruyenToiSTM32EditField.Value = "z";
                        end
        end

        % Button pushed function: HomeButton
        function Home(app, event)
                        if ~isempty(app.s) && isvalid(app.s)
                            write(app.s, "h", "string"); % Gửi chữ R (Reverse)
                            disp("Đã gửi lệnh: (h)");
                            app.TruyenToiSTM32EditField.Value = "h";
                        end 
        end

        % Selection changed function: EncoderModeButtonGroup
        function EncoderMode(app, event)
            selectedButton = app.EncoderModeButtonGroup.SelectedObject;
            if selectedButton == app.Mode1Button
                fprintf(app.s, "g");
            elseif selectedButton == app.Mode2Button
                fprintf(app.s, "x");
            elseif selectedButton == app.Mode3Button
                fprintf(app.s, "v");
            elseif selectedButton == app.Mode4Button %%custom Mode
                fprintf(app.s, "n");
            elseif selectedButton == app.ResetButton_2
                fprintf(app.s, "j");
            end 
            
        end

        % Button pushed function: UpdatePIDButton
        function PIDUpdate(app, event)
            Kp = app.HsPEditField.Value; %%
            Ki = app.HsIEditField.Value; %%
            Kd = app.HsDEditField.Value;%%
            app.PIDEditField.Value = Kp + Ki + Kd; %% Hiển thị
            Speed_target = app.SetpointSpeedPIDEditField.Value; %%
            Vel_scale = 91.0/10000.0;
            Pos_scale = 8.0/422.4;
            SetpoinPulsesVel = app.PulsesEditField.Value * Vel_scale; %%
            SetpoinPulsesPos = app.PulsesEditField.Value * Pos_scale; %%
            SetPos = app.VtrmongmunEditField.Value; % Lấy thêm giá trị này
            selectedButton = app.SetpointPulsesButtonGroup.SelectedObject; %% đây là mode nếu sử dụng xung
            if selectedButton == app.NoPulsesButton %%Không sử dụng xung
                cmd = sprintf("P %.2f I %.2f D %.2f M %.2f O %.2f\n", Kp, Ki, Kd, Speed_target, SetPos); %%
            else 
                %%Nếu sử dụng chế độ dùng xung PULSES
                cmd = sprintf("P %.2f I %.2f D %.2f M %.2f O %.2f\n", Kp, Ki, Kd, SetpoinPulsesVel, SetpoinPulsesPos); %%
            end
           % cmd = sprintf("P %.2f I %.2f D %.2f S %.2f M %.2f\n", Kp, Ki, Kd, SetPos, Speed_target);
            if ~isempty(app.s) && isvalid(app.s)
                write(app.s, cmd, "string");
                % Hiển thị lên ô Raw Data gửi đi để check
                app.TruyenToiSTM32EditField.Value = cmd;
                app.PosPulsesEditField.Value = SetpoinPulsesPos;
            end
        end

        % Button pushed function: UpdateEncoderModeButton
        function UpdateEncoderMode(app, event)
            value = app.CustomEncoderPulsesCheckBox.Value;
            Kp = app.HsPEditField.Value;
            Ki = app.HsIEditField.Value;
            Kd = app.HsDEditField.Value;
            accel = app.AccelDistanceEditField.Value;
            maintain = app.MaintainDistanceEditField.Value;
            decel = app.DecelDistanceEditField.Value;
            app.PIDEditField.Value = Kp + Ki + Kd;
            cmd ="";
            if value == 1
                cmd = sprintf("C %.2f I %.2f D %.2f A %.2f T %.2f d %.2f\n", Kp, Ki, Kd, accel, maintain, decel);
            end
            if ~isempty(app.s) && isvalid(app.s)
                write(app.s, cmd, "string");
                % Hiển thị lên ô Raw Data gửi đi để check
                app.TruyenToiSTM32EditField.Value = cmd;
                
            end
        end

        % Value changing function: KpSlider
        function KpChanging(app, event)
            newP = event.Value; % Lấy giá trị đang kéo
            app.KpSlider.Value = newP; % Cập nhật ô số bên cạnh
            % Gọi hàm gửi: Lấy P mới, còn I và D lấy giá trị hiện tại trên Slider
            app.SendAllPID(newP, app.KiSlider.Value, app.KdSlider.Value);
            
        end

        % Value changing function: KiSlider
        function KiChanging(app, event)
            newI = event.Value;
            app.KiSlider.Value = newI;
            app.SendAllPID(app.KpSlider.Value, newI, app.KdSlider.Value);
            
        end

        % Value changing function: KdSlider
        function Kdchanging(app, event)
            newD = event.Value;
            app.KdSlider.Value = newD;
            app.SendAllPID(app.KpSlider.Value, app.KiSlider.Value, newD);
            
        end

        % Button pushed function: DaoChieuMotorButton
        function DaoChieu(app, event)
             if ~isempty(app.s) && isvalid(app.s)
                write(app.s, "r", "string"); % Gửi chữ R (Reverse)
                disp("Đã gửi lệnh: BACKWARD (r)");
                app.TruyenToiSTM32EditField.Value = "r";
            end
        end

        % Button pushed function: ThuanChieuMotorButton
        function ThuanChieu(app, event)
             if ~isempty(app.s) && isvalid(app.s)
                write(app.s, "f", "string"); % Gửi chữ R (Reverse)
                disp("Đã gửi lệnh: BACKWARD (f)");
                app.TruyenToiSTM32EditField.Value = "f";
            end
        end

        % Button pushed function: ExcelTableExportButton
        function ExportButtonPushed(app, event)
            writetable(app.Datatable, 'KetQua_VanhHanh.xlsx');
            msgbox('Đã xuất file Excel thành công!', 'Thông báo');
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Biểu đồ tốc độ')
            xlabel(app.UIAxes, 'Thời gian')
            ylabel(app.UIAxes, 'vận tốc')
            zlabel(app.UIAxes, 'Z')
            app.MotorControllerButtonGroup = uibuttongroup(app.UIFigure);
            app.MotorControllerButtonGroup.Title = 'Motor Controller';
            app.SpeedSliderLabel = uilabel(app.MotorControllerButtonGroup);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.MotorControllerButtonGroup);
            app.SpeedSlider.Limits = [0 1000];
            app.SpeedSlider.MajorTicks = [0 200 400 600 800];
            app.SpeedSlider.ValueChangingFcn = createCallbackFcn(app, @SpeedChange, true);
            app.GaugeLabel = uilabel(app.MotorControllerButtonGroup);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.MotorControllerButtonGroup, 'semicircular');
            app.Gauge.Limits = [0 800];
            app.Gauge.MajorTicks = [0 250 500 750];
            app.PWMEditFieldLabel = uilabel(app.MotorControllerButtonGroup);
            app.PWMEditFieldLabel.Text = 'PWM';
            app.PWMEditField = uieditfield(app.MotorControllerButtonGroup, 'numeric');
            app.STOPButton = uibutton(app.MotorControllerButtonGroup, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.DaoChieuMotorButton = uibutton(app.MotorControllerButtonGroup, 'push');
            app.DaoChieuMotorButton.ButtonPushedFcn = createCallbackFcn(app, @DaoChieu, true);
            app.DaoChieuMotorButton.Text = 'DaoChieuMotor';
            app.CFLeftEditFieldLabel = uilabel(app.MotorControllerButtonGroup);
            app.CFLeftEditFieldLabel.Text = 'CF Left';
            app.CFLeftEditField = uieditfield(app.MotorControllerButtonGroup, 'numeric');
            app.CFRightEditFieldLabel = uilabel(app.MotorControllerButtonGroup);
            app.CFRightEditFieldLabel.Text = 'CF Right';
            app.CFRightEditField = uieditfield(app.MotorControllerButtonGroup, 'numeric');
            app.ResetDriverButton = uibutton(app.MotorControllerButtonGroup, 'push');
            app.ResetDriverButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDriver, true);
            app.ResetDriverButton.Text = 'ResetDriver';
            app.ThuanChieuMotorButton = uibutton(app.MotorControllerButtonGroup, 'push');
            app.ThuanChieuMotorButton.ButtonPushedFcn = createCallbackFcn(app, @ThuanChieu, true);
            app.ThuanChieuMotorButton.Text = 'ThuanChieuMotor';
            app.OnlyuseinManualLabel = uilabel(app.MotorControllerButtonGroup);
            app.OnlyuseinManualLabel.Text = 'Only use in Manual ';
            app.PIDControllerPanel = uipanel(app.UIFigure);
            app.PIDControllerPanel.Title = 'PID Controller ';
            app.HsIEditFieldLabel = uilabel(app.PIDControllerPanel);
            app.HsIEditFieldLabel.Text = 'Hệ số I';
            app.HsIEditField = uieditfield(app.PIDControllerPanel, 'numeric');
            app.HsDEditFieldLabel = uilabel(app.PIDControllerPanel);
            app.HsDEditFieldLabel.Text = 'Hệ số D';
            app.HsDEditField = uieditfield(app.PIDControllerPanel, 'numeric');
            app.HsPEditFieldLabel = uilabel(app.PIDControllerPanel);
            app.HsPEditFieldLabel.Text = 'Hệ số P';
            app.HsPEditField = uieditfield(app.PIDControllerPanel, 'numeric');
            app.SetpointSpeedPIDEditFieldLabel = uilabel(app.PIDControllerPanel);
            app.SetpointSpeedPIDEditFieldLabel.Text = 'SetpointSpeedPID';
            app.SetpointSpeedPIDEditField = uieditfield(app.PIDControllerPanel, 'numeric');
            app.UpdatePIDButton = uibutton(app.PIDControllerPanel, 'push');
            app.UpdatePIDButton.ButtonPushedFcn = createCallbackFcn(app, @PIDUpdate, true);
            app.UpdatePIDButton.Text = 'Update PID';
            app.PIDEditFieldLabel = uilabel(app.PIDControllerPanel);
            app.PIDEditFieldLabel.Text = 'PID';
            app.PIDEditField = uieditfield(app.PIDControllerPanel, 'numeric');
            app.SetpointPulsesButtonGroup = uibuttongroup(app.PIDControllerPanel);
            app.SetpointPulsesButtonGroup.Title = 'Setpoint Pulses';
            app.NoPulsesButton = uiradiobutton(app.SetpointPulsesButtonGroup);
            app.NoPulsesButton.Text = 'No Pulses';
            app.NoPulsesButton.Value = true;
            app.YesButton = uiradiobutton(app.SetpointPulsesButtonGroup);
            app.YesButton.Text = 'Yes';
            app.PulsesEditFieldLabel = uilabel(app.PIDControllerPanel);
            app.PulsesEditFieldLabel.Text = 'Pulses';
            app.PulsesEditField = uieditfield(app.PIDControllerPanel, 'numeric');
            app.PIDmodeButtonGroup = uibuttongroup(app.PIDControllerPanel);
            app.PIDmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PIDMode, true);
            app.PIDmodeButtonGroup.Title = 'PID mode';
            app.VelocityButton = uiradiobutton(app.PIDmodeButtonGroup);
            app.VelocityButton.Text = 'Velocity';
            app.VelocityButton.Value = true;
            app.PositionButton = uiradiobutton(app.PIDmodeButtonGroup);
            app.PositionButton.Text = 'Position';
            app.ManualButton = uiradiobutton(app.PIDmodeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.VelPotButton = uiradiobutton(app.PIDmodeButtonGroup);
            app.VelPotButton.Text = 'VelPot';
            app.EncoderModeButton = uiradiobutton(app.PIDmodeButtonGroup);
            app.EncoderModeButton.Text = 'EncoderMode';
            app.ResetButton = uiradiobutton(app.PIDmodeButtonGroup);
            app.ResetButton.Text = 'Reset';
            app.PosPulsesEditFieldLabel = uilabel(app.PIDControllerPanel);
            app.PosPulsesEditFieldLabel.Text = 'PosPulses';
            app.PosPulsesEditField = uieditfield(app.PIDControllerPanel, 'numeric');
            app.EncoderPanel = uipanel(app.UIFigure);
            app.EncoderPanel.Title = 'Encoder';
            app.BnknhvtmeEditFieldLabel = uilabel(app.EncoderPanel);
            app.BnknhvtmeEditFieldLabel.Text = 'Bán kính vít me';
            app.BnknhvtmeEditField = uieditfield(app.EncoderPanel, 'numeric');
            app.BnknhvtmeEditField.Value = 8;
            app.XunghintiEditFieldLabel = uilabel(app.EncoderPanel);
            app.XunghintiEditFieldLabel.Text = 'Xung hiện tại';
            app.XunghintiEditField = uieditfield(app.EncoderPanel, 'numeric');
            app.VtrhintiEditFieldLabel = uilabel(app.EncoderPanel);
            app.VtrhintiEditFieldLabel.Text = 'Vị trí hiện tại';
            app.VtrhintiEditField = uieditfield(app.EncoderPanel, 'numeric');
            app.VtrmongmunEditFieldLabel = uilabel(app.EncoderPanel);
            app.VtrmongmunEditFieldLabel.Text = 'Vị trí mong muốn';
            app.VtrmongmunEditField = uieditfield(app.EncoderPanel, 'numeric');
            app.ThngsvtrLabel = uilabel(app.EncoderPanel);
            app.ThngsvtrLabel.Text = 'Thông số vị trí';
            app.ThngscngtcLabel = uilabel(app.EncoderPanel);
            app.ThngscngtcLabel.Text = 'Thông số công tác';
            app.EncoderModeButtonGroup = uibuttongroup(app.EncoderPanel);
            app.EncoderModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EncoderMode, true);
            app.EncoderModeButtonGroup.Title = 'Encoder Mode';
            app.Mode1Button = uiradiobutton(app.EncoderModeButtonGroup);
            app.Mode1Button.Text = 'Mode 1';
            app.Mode1Button.Value = true;
            app.Mode2Button = uiradiobutton(app.EncoderModeButtonGroup);
            app.Mode2Button.Text = 'Mode 2';
            app.Mode3Button = uiradiobutton(app.EncoderModeButtonGroup);
            app.Mode3Button.Text = 'Mode 3';
            app.Mode4Button = uiradiobutton(app.EncoderModeButtonGroup);
            app.Mode4Button.Text = 'Mode 4';
            app.HomeButton = uibutton(app.EncoderModeButtonGroup, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @Home, true);
            app.HomeButton.Text = 'Home';
            app.SetZeroButton = uibutton(app.EncoderModeButtonGroup, 'push');
            app.SetZeroButton.ButtonPushedFcn = createCallbackFcn(app, @SetZero, true);
            app.SetZeroButton.Text = 'Set Zero';
            app.ResetButton_2 = uiradiobutton(app.EncoderModeButtonGroup);
            app.ResetButton_2.Text = 'Reset';
            app.SaisEditField_2Label = uilabel(app.EncoderPanel);
            app.SaisEditField_2Label.Text = 'Sai số';
            app.SaisEditField_2 = uieditfield(app.EncoderPanel, 'numeric');
            app.DliuthvthngsktniPanel = uipanel(app.UIFigure);
            app.DliuthvthngsktniPanel.Title = 'Dữ liệu thô và thông số kết nối';
            app.NhantuStm32EditFieldLabel = uilabel(app.DliuthvthngsktniPanel);
            app.NhantuStm32EditFieldLabel.Text = 'NhantuStm32';
            app.NhantuStm32EditField = uieditfield(app.DliuthvthngsktniPanel, 'text');
            app.BaudrateEditFieldLabel = uilabel(app.DliuthvthngsktniPanel);
            app.BaudrateEditFieldLabel.Text = 'Baudrate';
            app.BaudrateEditField = uieditfield(app.DliuthvthngsktniPanel, 'text');
            app.BaudrateEditField.Value = '115200';
            app.TruyenToiSTM32EditFieldLabel = uilabel(app.DliuthvthngsktniPanel);
            app.TruyenToiSTM32EditFieldLabel.Text = 'TruyenToiSTM32';
            app.TruyenToiSTM32EditField = uieditfield(app.DliuthvthngsktniPanel, 'text');
            app.COMPortEditFieldLabel = uilabel(app.DliuthvthngsktniPanel);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.DliuthvthngsktniPanel, 'text');
            app.COMPortEditField.Value = 'COM3';
            app.CONNECTButton = uibutton(app.DliuthvthngsktniPanel, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @Connect, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.ExcelTableExportButton = uibutton(app.DliuthvthngsktniPanel, 'push');
            app.ExcelTableExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExcelTableExportButton.Text = 'ExcelTableExport';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PosAxisTab = uitab(app.TabGroup);
            app.PosAxisTab.Title = 'PosAxis';
            app.UIAxes_2 = uiaxes(app.PosAxisTab);
            title(app.UIAxes_2, 'Biểu đồ vị trí')
            xlabel(app.UIAxes_2, 'Thời gian')
            ylabel(app.UIAxes_2, 'Vị trí')
            zlabel(app.UIAxes_2, 'Z')
            app.CustomeMode_ETab = uitab(app.TabGroup);
            app.CustomeMode_ETab.Title = 'Custome Mode_E';
            app.AccelDistanceEditFieldLabel = uilabel(app.CustomeMode_ETab);
            app.AccelDistanceEditFieldLabel.Text = 'Accel Distance';
            app.AccelDistanceEditField = uieditfield(app.CustomeMode_ETab, 'numeric');
            app.MaintainDistanceEditFieldLabel = uilabel(app.CustomeMode_ETab);
            app.MaintainDistanceEditFieldLabel.Text = 'Maintain Distance';
            app.MaintainDistanceEditField = uieditfield(app.CustomeMode_ETab, 'numeric');
            app.DecelDistanceEditFieldLabel = uilabel(app.CustomeMode_ETab);
            app.DecelDistanceEditFieldLabel.Text = 'Decel Distance';
            app.DecelDistanceEditField = uieditfield(app.CustomeMode_ETab, 'numeric');
            app.UpdateEncoderModeButton = uibutton(app.CustomeMode_ETab, 'push');
            app.UpdateEncoderModeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateEncoderMode, true);
            app.UpdateEncoderModeButton.Text = 'Update EncoderMode';
            app.CustomEncoderPulsesCheckBox = uicheckbox(app.CustomeMode_ETab);
            app.CustomEncoderPulsesCheckBox.Text = 'Custom Encoder Pulses';
            app.SliderPIDtuningTab = uitab(app.TabGroup);
            app.SliderPIDtuningTab.Title = 'Slider PID tuning';
            app.KpSliderLabel = uilabel(app.SliderPIDtuningTab);
            app.KpSliderLabel.Text = 'Kp';
            app.KpSlider = uislider(app.SliderPIDtuningTab);
            app.KpSlider.MajorTicks = [20 40 60 80 100];
            app.KpSlider.ValueChangingFcn = createCallbackFcn(app, @KpChanging, true);
            app.KiSliderLabel = uilabel(app.SliderPIDtuningTab);
            app.KiSliderLabel.Text = 'Ki';
            app.KiSlider = uislider(app.SliderPIDtuningTab);
            app.KiSlider.Limits = [0 10];
            app.KiSlider.ValueChangingFcn = createCallbackFcn(app, @KiChanging, true);
            app.KdSliderLabel = uilabel(app.SliderPIDtuningTab);
            app.KdSliderLabel.Text = 'Kd';
            app.KdSlider = uislider(app.SliderPIDtuningTab);
            app.KdSlider.Limits = [0 10];
            app.KdSlider.ValueChangingFcn = createCallbackFcn(app, @Kdchanging, true);
            app.SlidePIDCheckBox = uicheckbox(app.SliderPIDtuningTab);
            app.SlidePIDCheckBox.Text = 'SlidePIDCheckBox';
            app.AngleEditFieldLabel = uilabel(app.SliderPIDtuningTab);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.SliderPIDtuningTab, 'numeric');
            app.PIDBLCTab = uitab(app.TabGroup);
            app.PIDBLCTab.Title = 'PIDBLC';
            app.SlidePIDBLCCheckBox = uicheckbox(app.PIDBLCTab);
            app.SlidePIDBLCCheckBox.Text = 'SlidePIDBLC';
            app.VelPSliderLabel = uilabel(app.PIDBLCTab);
            app.VelPSliderLabel.Text = 'VelP';
            app.VelPSlider = uislider(app.PIDBLCTab);
            app.VelISliderLabel = uilabel(app.PIDBLCTab);
            app.VelISliderLabel.Text = 'VelI';
            app.VelISlider = uislider(app.PIDBLCTab);
            app.VelISlider.Limits = [0 10];
            app.VelDSliderLabel = uilabel(app.PIDBLCTab);
            app.VelDSliderLabel.Text = 'VelD';
            app.VelDSlider = uislider(app.PIDBLCTab);
            app.VelDSlider.Limits = [0 5];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotorControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end